classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PorousLabUIFigure          matlab.ui.Figure
        ModelnameEditField         matlab.ui.control.EditField
        ModelnameEditFieldLabel    matlab.ui.control.Label
        AnalysistypeDropDown       matlab.ui.control.DropDown
        AnalysistypeDropDownLabel  matlab.ui.control.Label
        PhysicsDropDownLabel       matlab.ui.control.Label
        CreatemodelButton          matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        PhysicsDropDown            matlab.ui.control.DropDown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all; clc;

            % Path to source directory
            src_dir = fullfile(fileparts(mfilename('fullpath')), '..', 'src');
            addpath(genpath(src_dir));
            
            print_header;

        end

        % Button pushed function: CreatemodelButton
        function CreatemodelButtonPushed(app, event)
            % Create the model
            if strcmp(app.PhysicsDropDown.Value,'M')
                mdl = Model_M();
            elseif strcmp(app.PhysicsDropDown.Value,'H')
                mdl = Model_H();
            elseif strcmp(app.PhysicsDropDown.Value,'HM')
                mdl = Model_HM();
            elseif strcmp(app.PhysicsDropDown.Value,'H2')
                mdl = Model_H2();
            end
            % Set the analysis type
            if strcmp(app.PhysicsDropDown.Value,'M') || strcmp(app.PhysicsDropDown.Value,'HM') || strcmp(app.PhysicsDropDown.Value,'H2M')
                if strcmp(app.AnalysistypeDropDown.Value,'Plane Stress')
                    app.mdl.isPlaneStress = true;
                elseif strcmp(app.AnalysistypeDropDown.Value,'Axissymetric')
                    app.mdl.isAxisSymmetric = true;
                end
            end
            % Set model name
            mdl.name = app.ModelnameEditField.Value;
            % Go the mesh step
            meshOptions(mdl);
            % Close this app
            delete(app);
        end

        % Value changed function: PhysicsDropDown
        function PhysicsDropDownValueChanged(app, event)
            if strcmp(app.PhysicsDropDown.Value,'M')
                app.AnalysistypeDropDown.Visible = "on";
                app.AnalysistypeDropDownLabel.Visible = "on";
            elseif strcmp(app.PhysicsDropDown.Value,'H')
                app.AnalysistypeDropDown.Visible = "off";
                app.AnalysistypeDropDownLabel.Visible = "off";
            elseif strcmp(app.PhysicsDropDown.Value,'HM')
                app.AnalysistypeDropDown.Visible = "on";
                app.AnalysistypeDropDownLabel.Visible = "on";
            elseif strcmp(app.PhysicsDropDown.Value,'H2')
                app.AnalysistypeDropDown.Visible = "off";
                app.AnalysistypeDropDownLabel.Visible = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PorousLabUIFigure = uifigure('Visible', 'off');
            app.PorousLabUIFigure.Name = 'PorousLab';
            app.PorousLabUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'logo_porouslab_vertical_reduced.png');
            app.PhysicsDropDown = uidropdown(app.PorousLabUIFigure);
            app.PhysicsDropDown.Items = {'M', 'H', 'H2', 'HM', 'H2M'};
            app.PhysicsDropDown.ValueChangedFcn = createCallbackFcn(app, @PhysicsDropDownValueChanged, true);
            app.PhysicsDropDown.Value = 'M';
            app.Image = uiimage(app.PorousLabUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'logo_porouslab_vertical.png');
            app.CreatemodelButton = uibutton(app.PorousLabUIFigure, 'push');
            app.CreatemodelButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemodelButtonPushed, true);
            app.CreatemodelButton.Text = 'Create model';
            app.PhysicsDropDownLabel = uilabel(app.PorousLabUIFigure);
            app.PhysicsDropDownLabel.Text = 'Physics';
            app.AnalysistypeDropDownLabel = uilabel(app.PorousLabUIFigure);
            app.AnalysistypeDropDownLabel.Text = 'Analysis type';
            app.AnalysistypeDropDown = uidropdown(app.PorousLabUIFigure);
            app.AnalysistypeDropDown.Items = {'Plane Strain', 'Plane Stress', 'Axissymetric'};
            app.AnalysistypeDropDown.Tooltip = {'Select the type of the model'};
            app.AnalysistypeDropDown.Value = 'Plane Strain';
            app.ModelnameEditFieldLabel = uilabel(app.PorousLabUIFigure);
            app.ModelnameEditFieldLabel.Text = 'Model name';
            app.ModelnameEditField = uieditfield(app.PorousLabUIFigure, 'text');
            app.ModelnameEditField.Tooltip = {'Give a name for the model to be exported'};
            app.ModelnameEditField.Value = 'testModel';
            % Show the figure after all components are created
            app.PorousLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PorousLabUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.PorousLabUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PorousLabUIFigure)
        end
    end
end