classdef meshOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PorousLabMeshUIFigure  matlab.ui.Figure
        MeshDropDown           matlab.ui.control.DropDown
        MeshDropDownLabel      matlab.ui.control.Label
        GeneratemeshButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        mdl;     % Model object
    end
    
    methods (Access = private)
        
        function importMeshData(app)
            % Open file dialog for the user to select a .mat file
            [file, path] = uigetfile('*.mat', 'Select a .mat file');
        
            % Check if user canceled the file selection
            if isequal(file, 0)
                disp('File selection canceled.');
                return;
            end

            % Full path to the selected file
            fullFile = fullfile(path, file);
        
            % Load the .mat file
            data = load(fullFile);

            % Check consistency and add it to the model
            if (isfield(data, 'node') && isfield(data, 'elem') )
                % Set mesh to model
                app.mdl.setMesh(data.node, data.elem);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mdl)
            app.mdl = mdl;
        end

        % Button pushed function: GeneratemeshButton
        function GeneratemeshButtonPushed(app, event)
            if strcmp(app.MeshDropDown.Value,'Rectangular domain with structured mesh')
                regMeshGeneration(app.mdl);
            elseif strcmp(app.MeshDropDown.Value,'Import')
                % Load the file .mat
                app.importMeshData();
                % Initialize the model configuration app
                if strcmp(app.mdl.physics,'M')
                    mechanicalModel(app.mdl);
                end
            end
            delete(app);
        end

        % Value changed function: MeshDropDown
        function MeshDropDownValueChanged(app, event)
            if strcmp(app.MeshDropDown.Value,'Rectangular domain with structured mesh')
                app.GeneratemeshButton.Text = 'Generate mesh';
            elseif strcmp(app.MeshDropDown.Value,'Import')
                app.GeneratemeshButton.Text = 'Import mesh';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PorousLabMeshUIFigure = uifigure('Visible', 'off');
            app.PorousLabMeshUIFigure.Name = 'PorousLab - Mesh';
            app.PorousLabMeshUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'logo_porouslab_vertical_reduced.png');
            app.GeneratemeshButton = uibutton(app.PorousLabMeshUIFigure, 'push');
            app.GeneratemeshButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratemeshButtonPushed, true);
            app.GeneratemeshButton.Text = 'Generate mesh';
            app.MeshDropDownLabel = uilabel(app.PorousLabMeshUIFigure);
            app.MeshDropDownLabel.Text = 'Mesh';
            app.MeshDropDown = uidropdown(app.PorousLabMeshUIFigure);
            app.MeshDropDown.Items = {'Import', 'Rectangular domain with structured mesh'};
            app.MeshDropDown.ValueChangedFcn = createCallbackFcn(app, @MeshDropDownValueChanged, true);
            app.MeshDropDown.Value = 'Rectangular domain with structured mesh';
            % Show the figure after all components are created
            app.PorousLabMeshUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = meshOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PorousLabMeshUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PorousLabMeshUIFigure)
        end
    end
end