classdef regMeshGeneration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PorousLabMeshgenerationUIFigure  matlab.ui.Figure
        NxEditField                matlab.ui.control.NumericEditField
        NxEditField_2Label         matlab.ui.control.Label
        NyEditField                matlab.ui.control.NumericEditField
        NyEditFieldLabel           matlab.ui.control.Label
        LyEditField                matlab.ui.control.NumericEditField
        LyEditFieldLabel           matlab.ui.control.Label
        LxEditField                matlab.ui.control.NumericEditField
        LxEditFieldLabel           matlab.ui.control.Label
        AddmeshButton              matlab.ui.control.Button
        DimensionsLabel            matlab.ui.control.Label
        NumberofsubdivisionsLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mdl;    % Model object
        femplot = FEMPlot();
    end
    
    methods (Access = private)
        
        %------------------------------------------------------------------
        % Plot the mesh
        function plotMesh(app)
            % Clear current mesh
            cla(app.UIAxes);
            % Convert the element cell into an array
            maxFaces = max(cellfun(@numel, app.mdl.ELEM));  
            faces = NaN(numel(app.mdl.ELEM), maxFaces); 
            for i = 1:numel(app.mdl.ELEM)
                faces(i, 1:numel(app.mdl.ELEM{i})) = app.mdl.ELEM{i}; 
            end
            % Plot
            patch(app.UIAxes, 'Faces', faces, ...
                      'Vertices', app.mdl.NODE,...
                      'FaceColor', 'g', ...  
                      'LineWidth', 0.7, ...
                      'LineStyle', '-', ...
                      'Marker', 'None');
            bbox = app.getBoundingBox();
            xlim(app.UIAxes, [bbox(1) bbox(3)]);
            ylim(app.UIAxes, [bbox(2) bbox(4)]);
            axis(app.UIAxes, 'equal');
        end

        %------------------------------------------------------------------
        % Set the mesh to the model
        function setMesh(app)
            % Get dimensions
            Lx = app.LxEditField.Value;
            Ly = app.LyEditField.Value;

            % Get sub-divisions
            Nx = app.NxEditField.Value;
            Ny = app.NyEditField.Value;
            
            % Generate regular mesh
            [node, elem] = regularMesh(Lx, Ly, Nx, Ny);

            % Set mesh to model
            app.mdl.setMesh(node, elem);
        end

        %------------------------------------------------------------------
        % Computes the bounding box of the model with a tolerance.
        function bbox = getBoundingBox(app)
            minX = Inf; minY = Inf; maxX = -Inf; maxY = -Inf;
            for el = 1:size(app.mdl.ELEM,1)
                minX = min(minX,min(app.mdl.NODE(:,1)));
                minY = min(minY,min(app.mdl.NODE(:,2)));
                maxX = max(maxX,max(app.mdl.NODE(:,1)));
                maxY = max(maxY,max(app.mdl.NODE(:,2)));
            end
            tolx = 0.10*abs(max(app.mdl.NODE(:,1)) - min(app.mdl.NODE(:,1)));
            toly = 0.10*abs(max(app.mdl.NODE(:,1)) - min(app.mdl.NODE(:,1)));
            bbox(1) = minX - tolx;
            bbox(2) = minY - toly;
            bbox(3) = maxX + tolx;
            bbox(4) = maxY + toly;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mdl)
            app.mdl = mdl;
            app.setMesh();
            app.plotMesh();
        end

        % Button pushed function: AddmeshButton
        function AddmeshButtonPushed(app, event)

            app.setMesh();

            % Initialize the model configuration app
            if strcmp(app.mdl.physics,'M')
                mechanicalModel(app.mdl);
            end

            % Delete this app
            delete(app);
        end

        % Value changed function: LxEditField
        function LxEditFieldValueChanged(app, event)
            app.setMesh();
            app.plotMesh();
        end

        % Value changed function: LyEditField
        function LyEditFieldValueChanged(app, event)
            app.setMesh();
            app.plotMesh();
        end

        % Value changed function: NxEditField
        function NxEditFieldValueChanged(app, event)
            app.setMesh();
            app.plotMesh();
        end

        % Value changed function: NyEditField
        function NyEditFieldValueChanged(app, event)
            app.setMesh();
            app.plotMesh();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PorousLabMeshgenerationUIFigure = uifigure('Visible', 'off');
            app.PorousLabMeshgenerationUIFigure.Name = 'PorousLab - Mesh generation';
            app.PorousLabMeshgenerationUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'logo_porouslab_vertical_reduced.png');
            app.UIAxes = uiaxes(app.PorousLabMeshgenerationUIFigure);
            app.NumberofsubdivisionsLabel = uilabel(app.PorousLabMeshgenerationUIFigure);
            app.NumberofsubdivisionsLabel.Text = 'Number of sub-divisions';
            app.DimensionsLabel = uilabel(app.PorousLabMeshgenerationUIFigure);
            app.DimensionsLabel.Text = 'Dimensions';
            app.AddmeshButton = uibutton(app.PorousLabMeshgenerationUIFigure, 'push');
            app.AddmeshButton.ButtonPushedFcn = createCallbackFcn(app, @AddmeshButtonPushed, true);
            app.AddmeshButton.Text = 'Add mesh';
            app.LxEditFieldLabel = uilabel(app.PorousLabMeshgenerationUIFigure);
            app.LxEditFieldLabel.Text = 'Lx';
            app.LxEditField = uieditfield(app.PorousLabMeshgenerationUIFigure, 'numeric');
            app.LxEditField.ValueChangedFcn = createCallbackFcn(app, @LxEditFieldValueChanged, true);
            app.LxEditField.Value = 3;
            app.LyEditFieldLabel = uilabel(app.PorousLabMeshgenerationUIFigure);
            app.LyEditFieldLabel.Text = 'Ly';
            app.LyEditField = uieditfield(app.PorousLabMeshgenerationUIFigure, 'numeric');
            app.LyEditField.ValueChangedFcn = createCallbackFcn(app, @LyEditFieldValueChanged, true);
            app.LyEditField.Value = 1;
            app.NyEditFieldLabel = uilabel(app.PorousLabMeshgenerationUIFigure);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyEditField = uieditfield(app.PorousLabMeshgenerationUIFigure, 'numeric');
            app.NyEditField.Limits = [1 200];
            app.NyEditField.RoundFractionalValues = 'on';
            app.NyEditField.ValueDisplayFormat = '%11d';
            app.NyEditField.ValueChangedFcn = createCallbackFcn(app, @NyEditFieldValueChanged, true);
            app.NyEditField.Value = 2;
            app.NxEditField_2Label = uilabel(app.PorousLabMeshgenerationUIFigure);
            app.NxEditField_2Label.Text = 'Nx';
            app.NxEditField = uieditfield(app.PorousLabMeshgenerationUIFigure, 'numeric');
            app.NxEditField.Limits = [1 200];
            app.NxEditField.RoundFractionalValues = 'on';
            app.NxEditField.ValueDisplayFormat = '%11d';
            app.NxEditField.ValueChangedFcn = createCallbackFcn(app, @NxEditFieldValueChanged, true);
            app.NxEditField.Value = 2;
            % Show the figure after all components are created
            app.PorousLabMeshgenerationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = regMeshGeneration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PorousLabMeshgenerationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PorousLabMeshgenerationUIFigure)
        end
    end
end